classdef mainscreen_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        rpm                           matlab.ui.Figure
        RCSPredictionwithMATLABLabel  matlab.ui.control.Label
        Utilities                     matlab.ui.control.Button
        Close                         matlab.ui.control.Button
        CalcRCS                       matlab.ui.control.Button
    end

    
    methods (Access = private)
        function leftpic_CreateFcn(app, hObject, eventdata, handles)
         
            axes(findobj('Tag','leftpic'));
            a=['pics\10.jpg'];
            imshow('pics\10.jpg');
        end
        
        function rightpic_CreateFcn(app, hObject, eventdata, handles)
           
            axes(findobj('Tag','rightpic'));
            a=['pics\11.jpg'];
            imshow('pics\11.jpg');
        end
        function addRuntimeConfigurations(app)

            app.Close.ButtonPushedFcn = 'h_figs = get(0,''children'');delete(h_figs);';
            app.Utilities.ButtonPushedFcn = 'utilities_App';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function mainscreen_OpeningFcn(app, varargin)
    
            global mainApp;
            mainApp = app.rpm;
            
            addRuntimeConfigurations(app);
            
            
            movegui(app.rpm, 'onscreen');
            
            
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            handles.output = hObject;
            
           
            guidata(hObject, handles);
        end

        % Button pushed function: CalcRCS
        function CalcRCS_Callback(app, event)
            
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

                         monostatic_App
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.rpm = uifigure('Visible', 'off');
            colormap(app.rpm, 'parula');
            app.rpm.Name = '   RCS PREDICTION USING PHYSICAL OPTICS APPROXIMATION';
            app.rpm.Tag = 'rpm';
            app.CalcRCS = uibutton(app.rpm, 'push');
            app.CalcRCS.ButtonPushedFcn = createCallbackFcn(app, @CalcRCS_Callback, true);
            app.CalcRCS.Tag = 'CalcMono';
            app.CalcRCS.Text = 'Calculate Monostatic RCS';
            app.Close = uibutton(app.rpm, 'push');
            app.Close.Tag = 'Close';
            app.Close.Text = 'Close';
            app.Utilities = uibutton(app.rpm, 'push');
            app.Utilities.Tag = 'Utilities';
            app.Utilities.Text = 'Utilities';
            app.RCSPredictionwithMATLABLabel = uilabel(app.rpm);
            app.RCSPredictionwithMATLABLabel.Text = 'RCS Prediction with MATLAB';
            % Show the figure after all components are created
            app.rpm.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainscreen_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.rpm)

            % Execute the startup function
            runStartupFcn(app, @(app)mainscreen_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.rpm)
        end
    end
end