classdef modelshow_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        modelshow  matlab.ui.Figure
        Save       matlab.ui.control.Button
        Close      matlab.ui.control.Button
        ModelAxis  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function CalcRCS_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in CalcRCS.
     
                        modelshow_App
        end
        
        function leftpic_CreateFcn(app, hObject, eventdata, handles)
            
            axes(findobj('Tag','leftpic'));
            a=['pics\10.jpg'];
            imshow('pics\10.jpg');
        end
        
        function rightpic_CreateFcn(app, hObject, eventdata, handles)
           
            axes(findobj('Tag','rightpic'));
            a=['pics\11.jpg'];
            imshow('pics\11.jpg');
        end
  
        function addRuntimeConfigurations(app)

            app.Close.ButtonPushedFcn = 'modelview Close';
            app.Save.ButtonPushedFcn = 'modelview Save;';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function modelshow_OpeningFcn(app, varargin)
            
            addRuntimeConfigurations(app);

            movegui(app.modelshow, 'onscreen');

            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            handles.output = hObject;

            guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.modelshow = uifigure('Visible', 'off');
            app.modelshow.Name = 'ShowModel';
            app.modelshow.Tag = 'showmodel';
            app.ModelAxis = uiaxes(app.modelshow);
            app.ModelAxis.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ModelAxis.LineStyleOrder = '--';
            app.ModelAxis.Tag = 'ModelAxis';
            app.Close = uibutton(app.modelshow, 'push');
            app.Close.Tag = 'Close';
            app.Close.Text = 'Close';
            app.Save = uibutton(app.modelshow, 'push');
            app.Save.Tag = 'Save';
            app.Save.Text = 'Save';
            % Show the figure after all components are created
            app.modelshow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modelshow_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.modelshow)

                % Execute the startup function
                runStartupFcn(app, @(app)modelshow_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.modelshow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.modelshow)
        end
    end
end