classdef monostatic_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        monostatic  matlab.ui.Figure
        Close       matlab.ui.control.Button
        Calculate   matlab.ui.control.Button
        LoadFile    matlab.ui.control.Button
    end

    
    methods (Access = private)
        function CalcRCS_Callback(app, hObject, eventdata, handles)
            
                         openfig('monostatic.fig');
        end
        
        function leftpic_CreateFcn(app, hObject, eventdata, handles)
            
            axes(findobj('Tag','leftpic'));
            a=['pics\10.jpg'];
            imshow('pics\10.jpg');
        end
        
        function rightpic_CreateFcn(app, hObject, eventdata, handles)
            axes(findobj('Tag','rightpic'));
            a=['pics\11.jpg'];
            imshow('pics\11.jpg');
        end

        function addRuntimeConfigurations(app)
            app.LoadFile.ButtonPushedFcn = 'planewave LoadFile;';
            app.Calculate.ButtonPushedFcn = 'planewave Calculate;';
            app.Close.ButtonPushedFcn = 'planewave Close;';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function monostatic_OpeningFcn(app, varargin)
         
            global monostaticApp; 
            monostaticApp = app.monostatic;
            addRuntimeConfigurations(app);
            movegui(app.monostatic, 'onscreen');   
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            handles.output = hObject;
            guidata(hObject, handles);
        end

        % Button pushed function: Close
        function CloseButtonPushed(app, event)
            global app;
            app.focus;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.monostatic = uifigure('Visible', 'off');
            colormap(app.monostatic, 'parula');
            app.monostatic.Name = ' CALCULATE MONOSTATIC RCS';
            app.monostatic.Tag = 'monostatic';
            app.LoadFile = uibutton(app.monostatic, 'push');
            app.LoadFile.Tag = 'LoadFile';
            app.LoadFile.Text = 'Load File';
            app.Calculate = uibutton(app.monostatic, 'push');
            app.Calculate.Tag = 'Calculate';
            app.Calculate.Enable = 'off';
            app.Calculate.Text = 'Calculate RCS';
            app.Close = uibutton(app.monostatic, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.Close.Tag = 'Close';
            app.Close.Text = 'Close';
            % Show the figure after all components are created
            app.monostatic.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = monostatic_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.monostatic)

                % Execute the startup function
                runStartupFcn(app, @(app)monostatic_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.monostatic)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.monostatic)
        end
    end
end